classdef hand_gesture_music_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DannaZaldumbideLabel           matlab.ui.control.Label
        AlexanderMotocheLabel          matlab.ui.control.Label
        NotasMusicalesdetectadasPanel  matlab.ui.container.Panel
        DOLamp_2                       matlab.ui.control.Lamp
        DOLamp_2Label                  matlab.ui.control.Label
        SILamp                         matlab.ui.control.Lamp
        SILampLabel                    matlab.ui.control.Label
        LALamp                         matlab.ui.control.Lamp
        LALampLabel                    matlab.ui.control.Label
        SOLLamp                        matlab.ui.control.Lamp
        SOLLampLabel                   matlab.ui.control.Label
        FALamp                         matlab.ui.control.Lamp
        FALampLabel                    matlab.ui.control.Label
        MILamp                         matlab.ui.control.Lamp
        MILampLabel                    matlab.ui.control.Label
        RELamp                         matlab.ui.control.Lamp
        RELampLabel                    matlab.ui.control.Label
        DOLamp                         matlab.ui.control.Lamp
        DOLampLabel                    matlab.ui.control.Label
        SistemadeReconocimientodeManosyGeneracindeNotasMusicalesLabel  matlab.ui.control.Label
        Laboratorio                    matlab.ui.control.Label
        FINALIZARVIDEOButton           matlab.ui.control.Button
        BUHOPOLI                       matlab.ui.control.Image
        EscuelaPolitnicaNacionalLabel  matlab.ui.control.Label
        INICIARVIDEOButton             matlab.ui.control.Button
        videoAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        videoObj
        running = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Desactivar el botón "FINALIZAR VIDEO" al inicio
            app.FINALIZARVIDEOButton.Enable = false;
    
            % Apagar todas las lámparas (color gris)
            lamps = [app.DOLamp, app.RELamp, app.MILamp, app.FALamp, ...
                    app.SOLLamp, app.LALamp, app.SILamp, app.DOLamp_2];

            for i = 1:length(lamps)
                lamps(i).Color = [0.5, 0.5, 0.5]; % Gris apagado
            end
        end

        % Button pushed function: INICIARVIDEOButton
        function INICIARVIDEOButtonPushed(app, event)
            app.videoObj = webcam; % Inicializa la cámara
            app.running = true;
            app.FINALIZARVIDEOButton.Enable = true;
            app.INICIARVIDEOButton.Enable = false;

            while app.running
                img = snapshot(app.videoObj); % Captura imagen de la cámara
                imshow(img, 'Parent', app.videoAxes); % Muestra en la interfaz

                % Aquí puedes llamar a una función de reconocimiento de gestos
                % detectedNote = recognizeGesture(img);

                % Simulación de notas detectadas (puedes reemplazar con IA)
                detectedNote = randi([1, 8]); % Número aleatorio para simular una nota

                % Enciende la lámpara correspondiente
                updateLamps(app, detectedNote);

                pause(0.1); % Pequeña pausa para actualizar la imagen
            end
        end

        % Button pushed function: FINALIZARVIDEOButton
        function FINALIZARVIDEOButtonPushed(app, event)
            app.running = false; % Detener el bucle de captura de video
            clear app.videoObj;  % Liberar la cámara

            % Restaurar estado de botones
            app.INICIARVIDEOButton.Enable = true;
            app.FINALIZARVIDEOButton.Enable = false;

            % Apagar todas las lámparas
            lamps = [app.DOLamp, app.RELamp, app.MILamp, app.FALamp, ...
                     app.SOLLamp, app.LALamp, app.SILamp, app.DOLamp_2];

            for i = 1:length(lamps)
                lamps(i).Color = [0.5, 0.5, 0.5]; % Apagado
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.videoAxes = uiaxes(app.UIFigure);
            title(app.videoAxes, 'Title')
            xlabel(app.videoAxes, 'X')
            ylabel(app.videoAxes, 'Y')
            zlabel(app.videoAxes, 'Z')
            app.INICIARVIDEOButton = uibutton(app.UIFigure, 'push');
            app.INICIARVIDEOButton.ButtonPushedFcn = createCallbackFcn(app, @INICIARVIDEOButtonPushed, true);
            app.INICIARVIDEOButton.Text = 'INICIAR VIDEO';
            app.EscuelaPolitnicaNacionalLabel = uilabel(app.UIFigure);
            app.EscuelaPolitnicaNacionalLabel.Text = 'Escuela Politénica Nacional';
            app.BUHOPOLI = uiimage(app.UIFigure);
            app.BUHOPOLI.ImageSource = fullfile(pathToMLAPP, 'BUHO_EPN-125x216.png');
            app.FINALIZARVIDEOButton = uibutton(app.UIFigure, 'push');
            app.FINALIZARVIDEOButton.ButtonPushedFcn = createCallbackFcn(app, @FINALIZARVIDEOButtonPushed, true);
            app.FINALIZARVIDEOButton.Text = 'FINALIZAR VIDEO';
            app.Laboratorio = uilabel(app.UIFigure);
            app.Laboratorio.Text = 'Laboratorio de Investigación en Inteligencia y Visión Artificial: Alan Turing';
            app.SistemadeReconocimientodeManosyGeneracindeNotasMusicalesLabel = uilabel(app.UIFigure);
            app.SistemadeReconocimientodeManosyGeneracindeNotasMusicalesLabel.Text = 'Sistema de Reconocimiento de Manos y Generación de Notas Musicales';
            app.NotasMusicalesdetectadasPanel = uipanel(app.UIFigure);
            app.NotasMusicalesdetectadasPanel.Title = 'Notas Musicales detectadas:';
            app.DOLampLabel = uilabel(app.NotasMusicalesdetectadasPanel);
            app.DOLampLabel.Text = 'DO';
            app.DOLamp = uilamp(app.NotasMusicalesdetectadasPanel);
            app.RELampLabel = uilabel(app.NotasMusicalesdetectadasPanel);
            app.RELampLabel.Text = 'RE';
            app.RELamp = uilamp(app.NotasMusicalesdetectadasPanel);
            app.MILampLabel = uilabel(app.NotasMusicalesdetectadasPanel);
            app.MILampLabel.Text = 'MI';
            app.MILamp = uilamp(app.NotasMusicalesdetectadasPanel);
            app.FALampLabel = uilabel(app.NotasMusicalesdetectadasPanel);
            app.FALampLabel.Text = 'FA';
            app.FALamp = uilamp(app.NotasMusicalesdetectadasPanel);
            app.SOLLampLabel = uilabel(app.NotasMusicalesdetectadasPanel);
            app.SOLLampLabel.Text = 'SOL';
            app.SOLLamp = uilamp(app.NotasMusicalesdetectadasPanel);
            app.LALampLabel = uilabel(app.NotasMusicalesdetectadasPanel);
            app.LALampLabel.Text = 'LA';
            app.LALamp = uilamp(app.NotasMusicalesdetectadasPanel);
            app.SILampLabel = uilabel(app.NotasMusicalesdetectadasPanel);
            app.SILampLabel.Text = 'SI';
            app.SILamp = uilamp(app.NotasMusicalesdetectadasPanel);
            app.DOLamp_2Label = uilabel(app.NotasMusicalesdetectadasPanel);
            app.DOLamp_2Label.Text = 'DO#';
            app.DOLamp_2 = uilamp(app.NotasMusicalesdetectadasPanel);
            app.AlexanderMotocheLabel = uilabel(app.UIFigure);
            app.AlexanderMotocheLabel.Text = 'Alexander Motoche';
            app.DannaZaldumbideLabel = uilabel(app.UIFigure);
            app.DannaZaldumbideLabel.Text = 'Danna Zaldumbide';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hand_gesture_music_ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end